classdef create_maneuver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ThrottleAxes                   matlab.ui.control.UIAxes
        SteeringAxes                   matlab.ui.control.UIAxes
        BrakeAxes                      matlab.ui.control.UIAxes
        GearAxes                       matlab.ui.control.UIAxes
        ThrottlePanel                  matlab.ui.container.Panel
        DurationLabel_2                matlab.ui.control.Label
        ThrottleDurationEditField      matlab.ui.control.NumericEditField
        FinalValueEditFieldLabel       matlab.ui.control.Label
        ThrottleFinalValueEditField    matlab.ui.control.NumericEditField
        ThrottleStepButton             matlab.ui.control.StateButton
        ThrottleRampButton             matlab.ui.control.StateButton
        ThrottleAddSectionLabel        matlab.ui.control.Label
        ThrottleDeleteSectionLabel     matlab.ui.control.Label
        ThrottleDeleteSectionDropDown  matlab.ui.control.DropDown
        IndexDropDownLabel             matlab.ui.control.Label
        ThrottleIndexDropDown          matlab.ui.control.DropDown
        ThrottleDeleteButton           matlab.ui.control.Button
        ThrottleAddButton              matlab.ui.control.Button
        InitialValueEditFieldLabel     matlab.ui.control.Label
        ThrottleInitialValueEditField  matlab.ui.control.NumericEditField
        ThrottleValidityImage          matlab.ui.control.Image
        SteeringPanel                  matlab.ui.container.Panel
        SteeringStepButton             matlab.ui.control.StateButton
        SteeringRampButton             matlab.ui.control.StateButton
        SteeringAddSectionLabel        matlab.ui.control.Label
        SteeringDeleteSectionLabel     matlab.ui.control.Label
        SteeringDeleteSectionDropDown  matlab.ui.control.DropDown
        DurationLabel                  matlab.ui.control.Label
        SteeringDurationEditField      matlab.ui.control.NumericEditField
        IndexDropDown_2Label           matlab.ui.control.Label
        SteeringIndexDropDown          matlab.ui.control.DropDown
        SteeringDeleteButton           matlab.ui.control.Button
        SteeringAddButton              matlab.ui.control.Button
        FinalValueEditFieldLabel_2     matlab.ui.control.Label
        SteeringFinalValueEditField    matlab.ui.control.NumericEditField
        InitialValueEditFieldLabel_2   matlab.ui.control.Label
        SteeringInitialValueEditField  matlab.ui.control.NumericEditField
        SteeringValidityImage          matlab.ui.control.Image
        BrakePanel                     matlab.ui.container.Panel
        BrakeStepButton                matlab.ui.control.StateButton
        BrakeRampButton                matlab.ui.control.StateButton
        BrakeAddSectionLabel           matlab.ui.control.Label
        BrakeDeleteSectionLabel        matlab.ui.control.Label
        BrakeDeleteSectionDropDown     matlab.ui.control.DropDown
        DurationLabel_3                matlab.ui.control.Label
        BrakeDurationEditField         matlab.ui.control.NumericEditField
        IndexDropDown_3Label           matlab.ui.control.Label
        BrakeIndexDropDown             matlab.ui.control.DropDown
        BrakeDeleteButton              matlab.ui.control.Button
        BrakeAddButton                 matlab.ui.control.Button
        FinalValueEditFieldLabel_3     matlab.ui.control.Label
        BrakeFinalValueEditField       matlab.ui.control.NumericEditField
        InitialValueEditFieldLabel_3   matlab.ui.control.Label
        BrakeInitialValueEditField     matlab.ui.control.NumericEditField
        BrakeValidityImage             matlab.ui.control.Image
        GearPanel                      matlab.ui.container.Panel
        GearAddSectionLabel            matlab.ui.control.Label
        GearDeleteSectionLabel         matlab.ui.control.Label
        GearDeleteSectionDropDown      matlab.ui.control.DropDown
        DurationLabel_4                matlab.ui.control.Label
        GearDurationEditField          matlab.ui.control.NumericEditField
        ValueLabel                     matlab.ui.control.Label
        GearValueEditField             matlab.ui.control.NumericEditField
        IndexDropDown_4Label           matlab.ui.control.Label
        GearIndexDropDown              matlab.ui.control.DropDown
        GearDeleteButton               matlab.ui.control.Button
        GearAddButton                  matlab.ui.control.Button
        GearValidityImage              matlab.ui.control.Image
        Panel                          matlab.ui.container.Panel
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        StopTimeEditField_2Label       matlab.ui.control.Label
        StopTimeEditField              matlab.ui.control.NumericEditField
        CreateManeuverLabel            matlab.ui.control.Label
        NameValidityLabel              matlab.ui.control.Label
        BottomPanel                    matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        StateLabel                     matlab.ui.control.Label
        ValidityLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ThrottleSections                cell
        SteeringSections                cell
        BrakeSections                   cell
        GearSections                    cell
        NameIsValid                     int8
    end
    
    methods (Access = private)
        
        function updateValidity(app)
            sectionsValidity = ...
                app.updateThrottleValidity && ...
                app.updateSteeringValidity && ...
                app.updateBrakeValidity && ...
                app.updateGearValidity;
            
            if app.StopTimeEditField == 0
                app.StateLabel.FontColor = [0.8 0 0];
                app.StateLabel.Text = 'Set a positive stop time';
                app.SaveButton.Enable = false;
            elseif ~app.NameIsValid
                app.StateLabel.FontColor = [0.8 0 0];
                app.StateLabel.Text = 'Enter a valid name';
                app.SaveButton.Enable = false;
            elseif ~sectionsValidity
                app.StateLabel.FontColor = [0.8 0 0];
                app.StateLabel.Text = 'Add more sections';
                app.SaveButton.Enable = false;
            else
                app.StateLabel.FontColor = [0 0.8 0];
                app.StateLabel.Text = 'Valid state';
                app.SaveButton.Enable = true;
            end
        end
        
        function validity = updateThrottleValidity(app)
            validity = ~isempty(app.ThrottleSections) && ...
                app.ThrottleSections{end}.getStopTime == app.StopTimeEditField.Value;
            if validity
                app.ThrottleValidityImage.ImageSource = 'Assets/Checkmark.png';
            else
                app.ThrottleValidityImage.ImageSource = 'Assets/Red x.jpg';
            end
        end
        
        function validity = updateSteeringValidity(app)
            validity = ~isempty(app.SteeringSections) && ...
                app.SteeringSections{end}.getStopTime == app.StopTimeEditField.Value;
            if validity
                app.SteeringValidityImage.ImageSource = 'Assets/Checkmark.png';
            else
                app.SteeringValidityImage.ImageSource = 'Assets/Red x.jpg';
            end
        end
        
        function validity = updateBrakeValidity(app)
            validity = ~isempty(app.BrakeSections) && ...
                app.BrakeSections{end}.getStopTime == app.StopTimeEditField.Value;
            if validity
                app.BrakeValidityImage.ImageSource = 'Assets/Checkmark.png';
            else
                app.BrakeValidityImage.ImageSource = 'Assets/Red x.jpg';
            end
        end
        
        function validity = updateGearValidity(app)
            validity = ~isempty(app.GearSections) && ...
                app.GearSections{end}.getStopTime == app.StopTimeEditField.Value;
            if validity
                app.GearValidityImage.ImageSource = 'Assets/Checkmark.png';
            else
                app.GearValidityImage.ImageSource = 'Assets/Red x.jpg';
            end
        end
        
        function updateThrottleDurationLimits(app)
            if isempty(app.ThrottleSections)
                maxDuration = app.StopTimeEditField.Value;
            else
                maxDuration = app.StopTimeEditField.Value - app.ThrottleSections{end}.getStopTime;
            end
            if maxDuration == 0
                app.ThrottleStepButton.Enable = false;
                app.ThrottleRampButton.Enable = false;
                app.ThrottleIndexDropDown.Enable = false;
                app.ThrottleDurationEditField.Enable = false;
                app.ThrottleInitialValueEditField.Enable = false;
                app.ThrottleFinalValueEditField.Enable = false;
                app.ThrottleAddButton.Enable = false;
            else
                app.ThrottleStepButton.Enable = true;
                app.ThrottleRampButton.Enable = true;
                app.ThrottleIndexDropDown.Enable = true;
                app.ThrottleDurationEditField.Enable = true;
                app.ThrottleInitialValueEditField.Enable = true;
                app.ThrottleFinalValueEditField.Enable = true;
                app.ThrottleAddButton.Enable = true;
                app.ThrottleDurationEditField.Limits = [0 maxDuration];
            end
        end
        
        function updateSteeringDurationLimits(app)
            if isempty(app.SteeringSections)
                maxDuration = app.StopTimeEditField.Value;
            else
                maxDuration = app.StopTimeEditField.Value - app.SteeringSections{end}.getStopTime;
            end
            if maxDuration == 0
                app.SteeringStepButton.Enable = false;
                app.SteeringRampButton.Enable = false;
                app.SteeringIndexDropDown.Enable = false;
                app.SteeringDurationEditField.Enable = false;
                app.SteeringInitialValueEditField.Enable = false;
                app.SteeringFinalValueEditField.Enable = false;
                app.SteeringAddButton.Enable = false;
            else
                app.SteeringStepButton.Enable = true;
                app.SteeringRampButton.Enable = true;
                app.SteeringIndexDropDown.Enable = true;
                app.SteeringDurationEditField.Enable = true;
                app.SteeringInitialValueEditField.Enable = true;
                app.SteeringFinalValueEditField.Enable = true;
                app.SteeringAddButton.Enable = true;
                app.SteeringDurationEditField.Limits = [0 maxDuration];
            end
        end
        
        function updateBrakeDurationLimits(app)
            if isempty(app.BrakeSections)
                maxDuration = app.StopTimeEditField.Value;
            else
                maxDuration = app.StopTimeEditField.Value - app.BrakeSections{end}.getStopTime;
            end
            if maxDuration == 0
                app.BrakeStepButton.Enable = false;
                app.BrakeRampButton.Enable = false;
                app.BrakeIndexDropDown.Enable = false;
                app.BrakeDurationEditField.Enable = false;
                app.BrakeInitialValueEditField.Enable = false;
                app.BrakeFinalValueEditField.Enable = false;
                app.BrakeAddButton.Enable = false;
            else
                app.BrakeStepButton.Enable = true;
                app.BrakeRampButton.Enable = true;
                app.BrakeIndexDropDown.Enable = true;
                app.BrakeDurationEditField.Enable = true;
                app.BrakeInitialValueEditField.Enable = true;
                app.BrakeFinalValueEditField.Enable = true;
                app.BrakeAddButton.Enable = true;
                app.BrakeDurationEditField.Limits = [0 maxDuration];
            end
        end
        
        function updateGearDurationLimits(app)
            if isempty(app.GearSections)
                maxDuration = app.StopTimeEditField.Value;
            else
                maxDuration = app.StopTimeEditField.Value - app.GearSections{end}.getStopTime;
            end
            if maxDuration == 0
                app.GearIndexDropDown.Enable = false;
                app.GearDurationEditField.Enable = false;
                app.GearValueEditField.Enable = false;
                app.GearAddButton.Enable = false;
            else
                app.GearIndexDropDown.Enable = true;
                app.GearDurationEditField.Enable = true;
                app.GearValueEditField.Enable = true;
                app.GearAddButton.Enable = false;
                app.GearDurationEditField.Limits = [0 maxDuration];
            end
        end
        
        function index = findItem(~, cellArray, item)
            index = -1;
            for i = 1:length(cellArray)
                if all(size(cellArray{i}) == size(item)) && all(cellArray{i} == item)
                    index = i;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.ThrottleIndexDropDown.Visible = false;
            app.ThrottleDurationEditField.Visible = false;
            app.ThrottleInitialValueEditField.Visible = false;
            app.ThrottleFinalValueEditField.Visible = false;
            app.ThrottleAddButton.Visible = false;
            
            app.SteeringIndexDropDown.Visible = false;
            app.SteeringDurationEditField.Visible = false;
            app.SteeringInitialValueEditField.Visible = false;
            app.SteeringFinalValueEditField.Visible = false;
            app.SteeringAddButton.Visible = false;
            
            app.BrakeIndexDropDown.Visible = false;
            app.BrakeDurationEditField.Visible = false;
            app.BrakeInitialValueEditField.Visible = false;
            app.BrakeFinalValueEditField.Visible = false;
            app.BrakeAddButton.Visible = false;
            
            disableDefaultInteractivity(app.ThrottleAxes);
            disableDefaultInteractivity(app.SteeringAxes);
            disableDefaultInteractivity(app.BrakeAxes);
            disableDefaultInteractivity(app.GearAxes);
            
            app.ThrottleAxes.Toolbar.Visible = 'off';
            app.SteeringAxes.Toolbar.Visible = 'off';
            app.BrakeAxes.Toolbar.Visible = 'off';
            app.GearAxes.Toolbar.Visible = 'off';
            
            hold(app.ThrottleAxes, 'on');
            hold(app.SteeringAxes, 'on');
            hold(app.BrakeAxes, 'on');
            hold(app.GearAxes, 'on');
            
            app.StopTimeEditField.LowerLimitInclusive = 'off';
            app.ThrottleDurationEditField.LowerLimitInclusive = 'off';
            app.SteeringDurationEditField.LowerLimitInclusive = 'off';
            app.BrakeDurationEditField.LowerLimitInclusive = 'off';
            app.GearDurationEditField.LowerLimitInclusive = 'off';
            
            app.updateValidity;
        end

        % Value changed function: StopTimeEditField
        function StopTimeValueChangedFcn(app, event)
            value = app.StopTimeEditField.Value;
            
            app.ThrottleAxes.XLim(2) = value;
            app.SteeringAxes.XLim(2) = value;
            app.BrakeAxes.XLim(2) = value;
            app.GearAxes.XLim(2) = value;
            
            if isempty(app.ThrottleSections)
                stopTime = value;
            else
                stopTime = value-app.ThrottleSections{end}.getStopTime;
            end
            app.ThrottleDurationEditField.Limits = [0 stopTime];
        end

        % Value changed function: ThrottleStepButton
        function ThrottleStepButtonPushed(app, event)
            value = app.ThrottleStepButton.Value;
            
            if value
                app.ThrottleRampButton.Value = false;
            end
            
            app.ThrottleIndexDropDown.Visible = value;
            app.ThrottleDurationEditField.Visible = value;
            app.ThrottleInitialValueEditField.Visible = value;
            app.ThrottleFinalValueEditField.Visible = false;
            app.ThrottleAddButton.Visible = value;
        end

        % Value changed function: ThrottleRampButton
        function ThrottleRampButtonPushed(app, event)
            value = app.ThrottleRampButton.Value;
            
            if value
                app.ThrottleStepButton.Value = false;
            end
            
            app.ThrottleIndexDropDown.Visible = value;
            app.ThrottleDurationEditField.Visible = value;
            app.ThrottleInitialValueEditField.Visible = value;
            app.ThrottleFinalValueEditField.Visible = value;
            app.ThrottleAddButton.Visible = value;
        end

        % Button pushed function: SteeringDeleteButton
        function SteeringDeleteButtonPushed(app, event)
            index = str2double(app.SteeringDeleteSectionDropDown.Value);
            numSections = length(app.SteeringSections);
            deletedDuration = app.SteeringSections{index}.Duration;
            
            delete(app.SteeringSections{index}.AxesSectionHandle);
            
            % Move everything above it down
            for i = index+1:numSections
                delete(app.SteeringSections{i}.AxesSectionHandle);
                app.SteeringSections{i-1} = app.SteeringSections{i};
                app.SteeringSections{i-1}.StartTime = app.SteeringSections{i}.StartTime-deletedDuration;
                if class(app.SteeringSections{i-1}) == "ManeuverStepSection"
                    yValues = [app.SteeringSections{i-1}.Value app.SteeringSections{i-1}.Value];
                else
                    yValues = [app.SteeringSections{i-1}.InitialValue app.SteeringSections{i-1}.FinalValue];
                end
                app.SteeringSections{i-1}.AxesSectionHandle = plot(app.SteeringAxes, ...
                    [app.SteeringSections{i-1}.StartTime app.SteeringSections{i-1}.getStopTime], ...
                    yValues);
                % Bump down the 'add' drop-down
                app.SteeringIndexDropDown.Items{i} = ...
                    strcat(num2str(app.SteeringSections{i-1}.getStopTime), ' s');
                % Bump down the 'delete' drop-down
                app.SteeringDeleteSectionDropDown.Items{i} = num2str(i);
            end
            % Delete the last section (since everything is now moved down)
            app.SteeringSections(numSections) = [];
            app.SteeringDeleteSectionDropDown.Items(numSections) = [];
            app.SteeringIndexDropDown.Items(numSections+1) = [];
            
            if numSections == 1
                app.SteeringDeleteButton.Enable = false;
            end
            
            app.updateSteeringDurationLimits;
            
            app.updateValidity();
        end

        % Button pushed function: ThrottleDeleteButton
        function ThrottleDeleteButtonPushed(app, event)
            index = str2double(app.ThrottleDeleteSectionDropDown.Value);
            numSections = length(app.ThrottleSections);
            deletedDuration = app.ThrottleSections{index}.Duration;
            
            delete(app.ThrottleSections{index}.AxesSectionHandle);
            
            % Move everything above it down
            for i = index+1:numSections
                delete(app.ThrottleSections{i}.AxesSectionHandle);
                app.ThrottleSections{i-1} = app.ThrottleSections{i};
                app.ThrottleSections{i-1}.StartTime = app.ThrottleSections{i}.StartTime-deletedDuration;
                if class(app.ThrottleSections{i-1}) == "ManeuverStepSection"
                    yValues = [app.ThrottleSections{i-1}.Value app.ThrottleSections{i-1}.Value];
                else
                    yValues = [app.ThrottleSections{i-1}.InitialValue app.ThrottleSections{i-1}.FinalValue];
                end
                app.ThrottleSections{i-1}.AxesSectionHandle = plot(app.ThrottleAxes, ...
                    [app.ThrottleSections{i-1}.StartTime app.ThrottleSections{i-1}.getStopTime], ...
                    yValues);
                % Bump down the 'add' drop-down
                app.ThrottleIndexDropDown.Items{i} = ...
                    strcat(num2str(app.ThrottleSections{i-1}.getStopTime), ' s');
                % Bump down the 'delete' drop-down
                app.ThrottleDeleteSectionDropDown.Items{i} = num2str(i);
            end
            % Delete the last section (since everything is now moved down)
            app.ThrottleSections(numSections) = [];
            app.ThrottleDeleteSectionDropDown.Items(numSections) = [];
            app.ThrottleIndexDropDown.Items(numSections+1) = [];
            
            if numSections == 1
                app.ThrottleDeleteButton.Enable = false;
            end
            
            app.updateThrottleDurationLimits;
            
            app.updateValidity();
        end

        % Button pushed function: ThrottleAddButton
        function ThrottleAddButtonPushed(app, event)
            % Number of sections before new one is added
            numSections = length(app.ThrottleSections);
            
            value = app.ThrottleIndexDropDown.Value;
            index = findItem(app, app.ThrottleIndexDropDown.Items, value);
            
            duration = app.ThrottleDurationEditField.Value;
            initialValue = app.ThrottleInitialValueEditField.Value;
            
            % If there are no sections yet or if user selects first index
            if numSections == 0 || index == 1
                startTime = 0;
            else
                startTime = app.ThrottleSections{index-1}.getStopTime;
            end
            
            % Bump up all sections after this index
            if numSections >= index
                for i = numSections:-1:index
                    app.ThrottleSections{i+1} = app.ThrottleSections{i};
                    % Update the section's start time property
                    app.ThrottleSections{i+1}.StartTime = app.ThrottleSections{i}.StartTime+duration;
                    % Remove the section's line from the graph
                    delete(app.ThrottleSections{i}.AxesSectionHandle);
                    if class(app.ThrottleSections{i}) == "ManeuverStepSection"
                        yValues = [app.ThrottleSections{i}.Value app.ThrottleSections{i}.Value];
                    else
                        yValues = [app.ThrottleSections{i}.InitialValue app.ThrottleSections{i}.FinalValue];
                    end
                    % Plot the new line
                    app.ThrottleSections{i+1}.AxesSectionHandle = ...
                        plot(app.ThrottleAxes, ...
                        [app.ThrottleSections{i+1}.StartTime app.ThrottleSections{i+1}.getStopTime], ...
                        yValues);
                    % Bump-up the 'add' drop-down
                    app.ThrottleIndexDropDown.Items{i + 2} = ...
                        strcat(num2str(app.ThrottleSections{i+1}.getStopTime), ' s');
                    % Bump-up the 'delete' drop-down
                    app.ThrottleDeleteSectionDropDown.Items{i + 1} = num2str(i+1);
                end
            end
            
            % Add this section
            if app.ThrottleStepButton.Value
                handle = plot(app.ThrottleAxes, [startTime startTime+duration], [initialValue initialValue]);
                app.ThrottleSections{index} = ManeuverStepSection(startTime, duration, handle, initialValue);
            else
                finalValue = app.ThrottleFinalValueEditField.Value;
                handle = plot(app.ThrottleAxes, [startTime startTime+duration], [initialValue finalValue]);
                app.ThrottleSections{index} = ManeuverRampSection(startTime, duration, handle, initialValue, finalValue);
            end
            
            app.ThrottleIndexDropDown.Items{index + 1} = strcat(num2str(startTime+duration), ' s');
            app.ThrottleDeleteSectionDropDown.Items{index} = num2str(index);
            
            app.ThrottleIndexDropDown.Value = app.ThrottleIndexDropDown.Items{index+1};
            
            app.ThrottleDeleteButton.Enable = true;
            
            app.updateValidity;
            app.updateThrottleDurationLimits;
        end

        % Button pushed function: SteeringAddButton
        function SteeringAddButtonPushed(app, event)
            % Number of sections before new one is added
            numSections = length(app.SteeringSections);
            
            value = app.SteeringIndexDropDown.Value;
            index = findItem(app, app.SteeringIndexDropDown.Items, value);
            
            duration = app.SteeringDurationEditField.Value;
            initialValue = app.SteeringInitialValueEditField.Value;
            
            % If there are no sections yet or if user selects first index
            if numSections == 0 || index == 1
                startTime = 0;
            else
                startTime = app.SteeringSections{index-1}.getStopTime;
            end
            
            % Bump up all sections after this index
            if numSections >= index
                for i = numSections:-1:index
                    app.SteeringSections{i+1} = app.SteeringSections{i};
                    % Update the section's start time property
                    app.SteeringSections{i+1}.StartTime = app.SteeringSections{i}.StartTime+duration;
                    % Remove the section's line from the graph
                    delete(app.SteeringSections{i}.AxesSectionHandle);
                    if class(app.SteeringSections{i}) == "ManeuverStepSection"
                        yValues = [app.SteeringSections{i}.Value app.SteeringSections{i}.Value];
                    else
                        yValues = [app.SteeringSections{i}.InitialValue app.SteeringSections{i}.FinalValue];
                    end
                    % Plot the new line
                    app.SteeringSections{i+1}.AxesSectionHandle = ...
                        plot(app.SteeringAxes, ...
                        [app.SteeringSections{i+1}.StartTime app.SteeringSections{i+1}.getStopTime], ...
                        yValues);
                    % Bump-up the 'add' drop-down
                    app.SteeringIndexDropDown.Items{i + 2} = ...
                        strcat(num2str(app.SteeringSections{i+1}.getStopTime), ' s');
                    % Bump-up the 'delete' drop-down
                    app.SteeringDeleteSectionDropDown.Items{i + 1} = num2str(i+1);
                end
            end
            
            % Add this section
            if app.SteeringStepButton.Value
                handle = plot(app.SteeringAxes, [startTime startTime+duration], [initialValue initialValue]);
                app.SteeringSections{index} = ManeuverStepSection(startTime, duration, handle, initialValue);
            else
                finalValue = app.SteeringFinalValueEditField.Value;
                handle = plot(app.SteeringAxes, [startTime startTime+duration], [initialValue finalValue]);
                app.SteeringSections{index} = ManeuverRampSection(startTime, duration, handle, initialValue, finalValue);
            end
            
            app.SteeringIndexDropDown.Items{index + 1} = strcat(num2str(startTime+duration), ' s');
            app.SteeringDeleteSectionDropDown.Items{index} = num2str(index);
            
            app.SteeringIndexDropDown.Value = app.SteeringIndexDropDown.Items{index+1};
            
            app.SteeringDeleteButton.Enable = true;
            
            app.updateValidity;
            app.updateSteeringDurationLimits;
        end

        % Button pushed function: BrakeDeleteButton
        function BrakeDeleteButtonPushed(app, event)
            index = str2double(app.BrakeDeleteSectionDropDown.Value);
            numSections = length(app.BrakeSections);
            deletedDuration = app.BrakeSections{index}.Duration;
            
            delete(app.BrakeSections{index}.AxesSectionHandle);
            
            % Move everything above it down
            for i = index+1:numSections
                delete(app.BrakeSections{i}.AxesSectionHandle);
                app.BrakeSections{i-1} = app.BrakeSections{i};
                app.BrakeSections{i-1}.StartTime = app.BrakeSections{i}.StartTime-deletedDuration;
                if class(app.BrakeSections{i-1}) == "ManeuverStepSection"
                    yValues = [app.BrakeSections{i-1}.Value app.BrakeSections{i-1}.Value];
                else
                    yValues = [app.BrakeSections{i-1}.InitialValue app.BrakeSections{i-1}.FinalValue];
                end
                app.BrakeSections{i-1}.AxesSectionHandle = plot(app.BrakeAxes, ...
                    [app.BrakeSections{i-1}.StartTime app.BrakeSections{i-1}.getStopTime], ...
                    yValues);
                % Bump down the 'add' drop-down
                app.BrakeIndexDropDown.Items{i} = ...
                    strcat(num2str(app.BrakeSections{i-1}.getStopTime), ' s');
                % Bump down the 'delete' drop-down
                app.BrakeDeleteSectionDropDown.Items{i} = num2str(i);
            end
            % Delete the last section (since everything is now moved down)
            app.BrakeSections(numSections) = [];
            app.BrakeDeleteSectionDropDown.Items(numSections) = [];
            app.BrakeIndexDropDown.Items(numSections+1) = [];
            
            if numSections == 1
                app.BrakeDeleteButton.Enable = false;
            end
            
            app.updateBrakeDurationLimits;
            
            app.updateValidity();
        end

        % Button pushed function: BrakeAddButton
        function BrakeAddButtonPushed(app, event)
            % Number of sections before new one is added
            numSections = length(app.BrakeSections);
            
            value = app.BrakeIndexDropDown.Value;
            index = findItem(app, app.BrakeIndexDropDown.Items, value);
            
            duration = app.BrakeDurationEditField.Value;
            initialValue = app.BrakeInitialValueEditField.Value;
            
            % If there are no sections yet or if user selects first index
            if numSections == 0 || index == 1
                startTime = 0;
            else
                startTime = app.BrakeSections{index-1}.getStopTime;
            end
            
            % Bump up all sections after this index
            if numSections >= index
                for i = numSections:-1:index
                    app.BrakeSections{i+1} = app.BrakeSections{i};
                    % Update the section's start time property
                    app.BrakeSections{i+1}.StartTime = app.BrakeSections{i}.StartTime+duration;
                    % Remove the section's line from the graph
                    delete(app.BrakeSections{i}.AxesSectionHandle);
                    if class(app.BrakeSections{i}) == "ManeuverStepSection"
                        yValues = [app.BrakeSections{i}.Value app.BrakeSections{i}.Value];
                    else
                        yValues = [app.BrakeSections{i}.InitialValue app.BrakeSections{i}.FinalValue];
                    end
                    % Plot the new line
                    app.BrakeSections{i+1}.AxesSectionHandle = ...
                        plot(app.BrakeAxes, ...
                        [app.BrakeSections{i+1}.StartTime app.BrakeSections{i+1}.getStopTime], ...
                        yValues);
                    % Bump-up the 'add' drop-down
                    app.BrakeIndexDropDown.Items{i + 2} = ...
                        strcat(num2str(app.BrakeSections{i+1}.getStopTime), ' s');
                    % Bump-up the 'delete' drop-down
                    app.BrakeDeleteSectionDropDown.Items{i + 1} = num2str(i+1);
                end
            end
            
            % Add this section
            if app.BrakeStepButton.Value
                handle = plot(app.BrakeAxes, [startTime startTime+duration], [initialValue initialValue]);
                app.BrakeSections{index} = ManeuverStepSection(startTime, duration, handle, initialValue);
            else
                finalValue = app.BrakeFinalValueEditField.Value;
                handle = plot(app.BrakeAxes, [startTime startTime+duration], [initialValue finalValue]);
                app.BrakeSections{index} = ManeuverRampSection(startTime, duration, handle, initialValue, finalValue);
            end
            
            app.BrakeIndexDropDown.Items{index + 1} = strcat(num2str(startTime+duration), ' s');
            app.BrakeDeleteSectionDropDown.Items{index} = num2str(index);
            
            app.BrakeIndexDropDown.Value = app.BrakeIndexDropDown.Items{index+1};
            
            app.BrakeDeleteButton.Enable = true;
            
            app.updateValidity;
            app.updateBrakeDurationLimits;
        end

        % Button pushed function: GearDeleteButton
        function GearDeleteButtonPushed(app, event)
            index = str2double(app.GearDeleteSectionDropDown.Value);
            numSections = length(app.GearSections);
            deletedDuration = app.GearSections{index}.Duration;
            
            delete(app.GearSections{index}.AxesSectionHandle);
            
            % Move everything above it down
            for i = index+1:numSections
                delete(app.GearSections{i}.AxesSectionHandle);
                app.GearSections{i-1} = app.GearSections{i};
                app.GearSections{i-1}.StartTime = app.GearSections{i}.StartTime-deletedDuration;
                if class(app.GearSections{i-1}) == "ManeuverStepSection"
                    yValues = [app.GearSections{i-1}.Value app.GearSections{i-1}.Value];
                else
                    yValues = [app.GearSections{i-1}.InitialValue app.GearSections{i-1}.FinalValue];
                end
                app.GearSections{i-1}.AxesSectionHandle = plot(app.GearAxes, ...
                    [app.GearSections{i-1}.StartTime app.GearSections{i-1}.getStopTime], ...
                    yValues);
                % Bump down the 'add' drop-down
                app.GearIndexDropDown.Items{i} = ...
                    strcat(num2str(app.GearSections{i-1}.getStopTime), ' s');
                % Bump down the 'delete' drop-down
                app.GearDeleteSectionDropDown.Items{i} = num2str(i);
            end
            % Delete the last section (since everything is now moved down)
            app.GearSections(numSections) = [];
            app.GearDeleteSectionDropDown.Items(numSections) = [];
            app.GearIndexDropDown.Items(numSections+1) = [];
            
            if numSections == 1
                app.GearDeleteButton.Enable = false;
            end
            
            app.updateGearDurationLimits;
            
            app.updateValidity();
        end

        % Button pushed function: GearAddButton
        function GearAddButtonPushed(app, event)
            % Number of sections before new one is added
            numSections = length(app.GearSections);
            
            value = app.GearIndexDropDown.Value;
            index = findItem(app, app.GearIndexDropDown.Items, value);
            
            duration = app.GearDurationEditField.Value;
            value = app.GearValueEditField.Value;
            
            % If there are no sections yet or if user selects first index
            if numSections == 0 || index == 1
                startTime = 0;
            else
                startTime = app.GearSections{index-1}.getStopTime;
            end
            
            % Bump up all sections after this index
            if numSections >= index
                for i = numSections:-1:index
                    app.GearSections{i+1} = app.GearSections{i};
                    % Update the section's start time property
                    app.GearSections{i+1}.StartTime = app.GearSections{i}.StartTime+duration;
                    % Remove the section's line from the graph
                    delete(app.GearSections{i}.AxesSectionHandle);
                    yValues = [app.GearSections{i}.Value app.GearSections{i}.Value];
                    % Plot the new line
                    app.GearSections{i+1}.AxesSectionHandle = ...
                        plot(app.GearAxes, ...
                        [app.GearSections{i+1}.StartTime app.GearSections{i+1}.getStopTime], ...
                        yValues);
                    % Bump-up the 'add' drop-down
                    app.GearIndexDropDown.Items{i + 2} = ...
                        strcat(num2str(app.GearSections{i+1}.getStopTime), ' s');
                    % Bump-up the 'delete' drop-down
                    app.GearDeleteSectionDropDown.Items{i + 1} = num2str(i+1);
                end
            end
            
            % Add this section
            handle = plot(app.GearAxes, [startTime startTime+duration], [value value]);
            app.GearSections{index} = ManeuverStepSection(startTime, duration, handle, value);
            
            app.GearIndexDropDown.Items{index + 1} = strcat(num2str(startTime+duration), ' s');
            app.GearDeleteSectionDropDown.Items{index} = num2str(index);
            
            app.GearIndexDropDown.Value = app.GearIndexDropDown.Items{index+1};
            
            app.GearDeleteButton.Enable = true;
            
            app.updateValidity;
            app.updateGearDurationLimits;
        end

        % Value changed function: SteeringStepButton
        function SteeringStepButtonPushed(app, event)
            value = app.SteeringStepButton.Value;
            
            if value
                app.SteeringRampButton.Value = false;
            end
            
            app.SteeringIndexDropDown.Visible = value;
            app.SteeringDurationEditField.Visible = value;
            app.SteeringInitialValueEditField.Visible = value;
            app.SteeringFinalValueEditField.Visible = false;
            app.SteeringAddButton.Visible = value;
        end

        % Value changed function: SteeringRampButton
        function SteeringRampButtonPushed(app, event)
            value = app.SteeringRampButton.Value;
            
            if value
                app.SteeringStepButton.Value = false;
            end
            
            app.SteeringIndexDropDown.Visible = value;
            app.SteeringDurationEditField.Visible = value;
            app.SteeringInitialValueEditField.Visible = value;
            app.SteeringFinalValueEditField.Visible = value;
            app.SteeringAddButton.Visible = value;
        end

        % Value changed function: BrakeStepButton
        function BrakeStepButtonPushed(app, event)
            value = app.BrakeStepButton.Value;
            
            if value
                app.BrakeRampButton.Value = false;
            end
            
            app.BrakeIndexDropDown.Visible = value;
            app.BrakeDurationEditField.Visible = value;
            app.BrakeInitialValueEditField.Visible = value;
            app.BrakeFinalValueEditField.Visible = false;
            app.BrakeAddButton.Visible = value;
        end

        % Value changed function: BrakeRampButton
        function BrakeRampButtonPushed(app, event)
            value = app.BrakeRampButton.Value;
            
            if value
                app.BrakeStepButton.Value = false;
            end
            
            app.BrakeIndexDropDown.Visible = value;
            app.BrakeDurationEditField.Visible = value;
            app.BrakeInitialValueEditField.Visible = value;
            app.BrakeFinalValueEditField.Visible = value;
            app.BrakeAddButton.Visible = value;
        end

        % Value changed function: GearValueEditField
        function GearValueChanged(app, event)
            value = app.GearValueEditField.Value;
            app.GearAddButton.Enable = mod(value,1) == 0;
        end

        % Button pushed function: SaveButton
        function SavedButtonPushed(app, event)
            name = app.NameEditField.Value;
            stopTime = app.StopTimeEditField.Value;
            throttleSections = app.ThrottleSections;
            steeringSections = app.SteeringSections;
            brakeSections = app.BrakeSections;
            gearSections = app.GearSections;
            save(strcat('Maneuvers/', app.NameEditField.Value, '.mat'), "name", "stopTime", "throttleSections", "steeringSections", "brakeSections", "gearSections");
            closereq
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq
        end

        % Value changing function: NameEditField
        function NameChanging(app, event)
            changingValue = event.Value;
            if isempty(regexp(changingValue, '^[a-zA-Z][\w]*$', 'once'))
                % Name is invalid
                app.NameValidityLabel.Text = 'Invalid name';
                app.NameValidityLabel.FontColor = [0.8 0 0];
                app.NameIsValid = false;
            elseif isfile(strcat('Maneuvers/', changingValue, '.mat'))
                % Name is taken
                app.NameValidityLabel.Text = 'Name is taken';
                app.NameValidityLabel.FontColor = [0.8 0 0];
                app.NameIsValid = false;
            else
                % Name is valid
                app.NameValidityLabel.Text = 'Valid name';
                app.NameValidityLabel.FontColor = [0 0.8 0];
                app.NameIsValid = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ThrottleAxes = uiaxes(app.GridLayout);
            title(app.ThrottleAxes, 'Throttle Input')
            xlabel(app.ThrottleAxes, 'Time (s)')
            ylabel(app.ThrottleAxes, 'Throttle (%)')
            app.ThrottleAxes.XLim = [0 1];
            app.ThrottleAxes.YLim = [0 100];
            app.SteeringAxes = uiaxes(app.GridLayout);
            title(app.SteeringAxes, 'Steering Input')
            xlabel(app.SteeringAxes, 'Time (s)')
            ylabel(app.SteeringAxes, 'Steering Angle (deg)')
            app.SteeringAxes.YLim = [-180 180];
            app.SteeringAxes.ZLim = [0 1];
            app.SteeringAxes.YTick = [-180 -90 0 90 180];
            app.SteeringAxes.YTickLabel = {'-180'; '-90'; '0'; '90'; '180'};
            app.BrakeAxes = uiaxes(app.GridLayout);
            title(app.BrakeAxes, 'Brake Input')
            xlabel(app.BrakeAxes, 'Time (s)')
            ylabel(app.BrakeAxes, 'Brake (%)')
            app.BrakeAxes.YLim = [0 100];
            app.GearAxes = uiaxes(app.GridLayout);
            title(app.GearAxes, 'Gear Input')
            xlabel(app.GearAxes, 'Time (s)')
            ylabel(app.GearAxes, 'Gear')
            app.GearAxes.YLim = [-0.2 6.2];
            app.GearAxes.YTick = [0 1 2 3 4 5 6];
            app.GearAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'};
            app.ThrottlePanel = uipanel(app.GridLayout);
            app.ThrottlePanel.Title = 'Throttle';
            app.DurationLabel_2 = uilabel(app.ThrottlePanel);
            app.DurationLabel_2.Text = 'Duration';
            app.ThrottleDurationEditField = uieditfield(app.ThrottlePanel, 'numeric');
            app.ThrottleDurationEditField.Limits = [0 1];
            app.FinalValueEditFieldLabel = uilabel(app.ThrottlePanel);
            app.FinalValueEditFieldLabel.Text = 'Final Value';
            app.ThrottleFinalValueEditField = uieditfield(app.ThrottlePanel, 'numeric');
            app.ThrottleFinalValueEditField.Limits = [0 100];
            app.ThrottleStepButton = uibutton(app.ThrottlePanel, 'state');
            app.ThrottleStepButton.ValueChangedFcn = createCallbackFcn(app, @ThrottleStepButtonPushed, true);
            app.ThrottleStepButton.Text = 'Step';
            app.ThrottleRampButton = uibutton(app.ThrottlePanel, 'state');
            app.ThrottleRampButton.ValueChangedFcn = createCallbackFcn(app, @ThrottleRampButtonPushed, true);
            app.ThrottleRampButton.Text = 'Ramp';
            app.ThrottleAddSectionLabel = uilabel(app.ThrottlePanel);
            app.ThrottleAddSectionLabel.Text = 'Add Section';
            app.ThrottleDeleteSectionLabel = uilabel(app.ThrottlePanel);
            app.ThrottleDeleteSectionLabel.Text = 'Delete Section';
            app.ThrottleDeleteSectionDropDown = uidropdown(app.ThrottlePanel);
            app.ThrottleDeleteSectionDropDown.Items = {};
            app.ThrottleDeleteSectionDropDown.Value = {};
            app.IndexDropDownLabel = uilabel(app.ThrottlePanel);
            app.IndexDropDownLabel.Text = 'Index';
            app.ThrottleIndexDropDown = uidropdown(app.ThrottlePanel);
            app.ThrottleIndexDropDown.Items = {'0 s'};
            app.ThrottleIndexDropDown.Value = '0 s';
            app.ThrottleDeleteButton = uibutton(app.ThrottlePanel, 'push');
            app.ThrottleDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @ThrottleDeleteButtonPushed, true);
            app.ThrottleDeleteButton.Enable = 'off';
            app.ThrottleDeleteButton.Text = 'Delete';
            app.ThrottleAddButton = uibutton(app.ThrottlePanel, 'push');
            app.ThrottleAddButton.ButtonPushedFcn = createCallbackFcn(app, @ThrottleAddButtonPushed, true);
            app.ThrottleAddButton.Text = 'Add';
            app.InitialValueEditFieldLabel = uilabel(app.ThrottlePanel);
            app.InitialValueEditFieldLabel.Text = 'Initial Value';
            app.ThrottleInitialValueEditField = uieditfield(app.ThrottlePanel, 'numeric');
            app.ThrottleInitialValueEditField.Limits = [0 100];
            app.ThrottleValidityImage = uiimage(app.ThrottlePanel);
            app.ThrottleValidityImage.ImageSource = 'Red x.jpg';
            app.SteeringPanel = uipanel(app.GridLayout);
            app.SteeringPanel.Title = 'Steering';
            app.SteeringStepButton = uibutton(app.SteeringPanel, 'state');
            app.SteeringStepButton.ValueChangedFcn = createCallbackFcn(app, @SteeringStepButtonPushed, true);
            app.SteeringStepButton.Text = 'Step';
            app.SteeringRampButton = uibutton(app.SteeringPanel, 'state');
            app.SteeringRampButton.ValueChangedFcn = createCallbackFcn(app, @SteeringRampButtonPushed, true);
            app.SteeringRampButton.Text = 'Ramp';
            app.SteeringAddSectionLabel = uilabel(app.SteeringPanel);
            app.SteeringAddSectionLabel.Text = 'Add Section';
            app.SteeringDeleteSectionLabel = uilabel(app.SteeringPanel);
            app.SteeringDeleteSectionLabel.Text = 'Delete Section';
            app.SteeringDeleteSectionDropDown = uidropdown(app.SteeringPanel);
            app.SteeringDeleteSectionDropDown.Items = {};
            app.SteeringDeleteSectionDropDown.Value = {};
            app.DurationLabel = uilabel(app.SteeringPanel);
            app.DurationLabel.Text = 'Duration';
            app.SteeringDurationEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.SteeringDurationEditField.Limits = [0 1];
            app.IndexDropDown_2Label = uilabel(app.SteeringPanel);
            app.IndexDropDown_2Label.Text = 'Index';
            app.SteeringIndexDropDown = uidropdown(app.SteeringPanel);
            app.SteeringIndexDropDown.Items = {'0 s'};
            app.SteeringIndexDropDown.Value = '0 s';
            app.SteeringDeleteButton = uibutton(app.SteeringPanel, 'push');
            app.SteeringDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @SteeringDeleteButtonPushed, true);
            app.SteeringDeleteButton.Enable = 'off';
            app.SteeringDeleteButton.Text = 'Delete';
            app.SteeringAddButton = uibutton(app.SteeringPanel, 'push');
            app.SteeringAddButton.ButtonPushedFcn = createCallbackFcn(app, @SteeringAddButtonPushed, true);
            app.SteeringAddButton.Text = 'Add';
            app.FinalValueEditFieldLabel_2 = uilabel(app.SteeringPanel);
            app.FinalValueEditFieldLabel_2.Text = 'Final Value';
            app.SteeringFinalValueEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.SteeringFinalValueEditField.Limits = [-2 2];
            app.InitialValueEditFieldLabel_2 = uilabel(app.SteeringPanel);
            app.InitialValueEditFieldLabel_2.Text = 'Initial Value';
            app.SteeringInitialValueEditField = uieditfield(app.SteeringPanel, 'numeric');
            app.SteeringInitialValueEditField.Limits = [-2 2];
            app.SteeringValidityImage = uiimage(app.SteeringPanel);
            app.SteeringValidityImage.ImageSource = 'Red x.jpg';
            app.BrakePanel = uipanel(app.GridLayout);
            app.BrakePanel.Title = 'Brake';
            app.BrakeStepButton = uibutton(app.BrakePanel, 'state');
            app.BrakeStepButton.ValueChangedFcn = createCallbackFcn(app, @BrakeStepButtonPushed, true);
            app.BrakeStepButton.Text = 'Step';
            app.BrakeRampButton = uibutton(app.BrakePanel, 'state');
            app.BrakeRampButton.ValueChangedFcn = createCallbackFcn(app, @BrakeRampButtonPushed, true);
            app.BrakeRampButton.Text = 'Ramp';
            app.BrakeAddSectionLabel = uilabel(app.BrakePanel);
            app.BrakeAddSectionLabel.Text = 'Add Section';
            app.BrakeDeleteSectionLabel = uilabel(app.BrakePanel);
            app.BrakeDeleteSectionLabel.Text = 'Delete Section';
            app.BrakeDeleteSectionDropDown = uidropdown(app.BrakePanel);
            app.BrakeDeleteSectionDropDown.Items = {};
            app.BrakeDeleteSectionDropDown.Value = {};
            app.DurationLabel_3 = uilabel(app.BrakePanel);
            app.DurationLabel_3.Text = 'Duration';
            app.BrakeDurationEditField = uieditfield(app.BrakePanel, 'numeric');
            app.BrakeDurationEditField.Limits = [0 1];
            app.IndexDropDown_3Label = uilabel(app.BrakePanel);
            app.IndexDropDown_3Label.Text = 'Index';
            app.BrakeIndexDropDown = uidropdown(app.BrakePanel);
            app.BrakeIndexDropDown.Items = {'0 s'};
            app.BrakeIndexDropDown.Value = '0 s';
            app.BrakeDeleteButton = uibutton(app.BrakePanel, 'push');
            app.BrakeDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @BrakeDeleteButtonPushed, true);
            app.BrakeDeleteButton.Enable = 'off';
            app.BrakeDeleteButton.Text = 'Delete';
            app.BrakeAddButton = uibutton(app.BrakePanel, 'push');
            app.BrakeAddButton.ButtonPushedFcn = createCallbackFcn(app, @BrakeAddButtonPushed, true);
            app.BrakeAddButton.Text = 'Add';
            app.FinalValueEditFieldLabel_3 = uilabel(app.BrakePanel);
            app.FinalValueEditFieldLabel_3.Text = 'Final Value';
            app.BrakeFinalValueEditField = uieditfield(app.BrakePanel, 'numeric');
            app.BrakeFinalValueEditField.Limits = [0 100];
            app.InitialValueEditFieldLabel_3 = uilabel(app.BrakePanel);
            app.InitialValueEditFieldLabel_3.Text = 'Initial Value';
            app.BrakeInitialValueEditField = uieditfield(app.BrakePanel, 'numeric');
            app.BrakeInitialValueEditField.Limits = [0 100];
            app.BrakeValidityImage = uiimage(app.BrakePanel);
            app.BrakeValidityImage.ImageSource = 'Red x.jpg';
            app.GearPanel = uipanel(app.GridLayout);
            app.GearPanel.Title = 'Gear';
            app.GearAddSectionLabel = uilabel(app.GearPanel);
            app.GearAddSectionLabel.Text = 'Add Section';
            app.GearDeleteSectionLabel = uilabel(app.GearPanel);
            app.GearDeleteSectionLabel.Text = 'Delete Section';
            app.GearDeleteSectionDropDown = uidropdown(app.GearPanel);
            app.GearDeleteSectionDropDown.Items = {};
            app.GearDeleteSectionDropDown.Value = {};
            app.DurationLabel_4 = uilabel(app.GearPanel);
            app.DurationLabel_4.Text = 'Duration';
            app.GearDurationEditField = uieditfield(app.GearPanel, 'numeric');
            app.GearDurationEditField.Limits = [0 1];
            app.ValueLabel = uilabel(app.GearPanel);
            app.ValueLabel.Text = 'Value';
            app.GearValueEditField = uieditfield(app.GearPanel, 'numeric');
            app.GearValueEditField.Limits = [-1 6];
            app.GearValueEditField.ValueChangedFcn = createCallbackFcn(app, @GearValueChanged, true);
            app.IndexDropDown_4Label = uilabel(app.GearPanel);
            app.IndexDropDown_4Label.Text = 'Index';
            app.GearIndexDropDown = uidropdown(app.GearPanel);
            app.GearIndexDropDown.Items = {'0 s'};
            app.GearIndexDropDown.Value = '0 s';
            app.GearDeleteButton = uibutton(app.GearPanel, 'push');
            app.GearDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @GearDeleteButtonPushed, true);
            app.GearDeleteButton.Enable = 'off';
            app.GearDeleteButton.Text = 'Delete';
            app.GearAddButton = uibutton(app.GearPanel, 'push');
            app.GearAddButton.ButtonPushedFcn = createCallbackFcn(app, @GearAddButtonPushed, true);
            app.GearAddButton.Text = 'Add';
            app.GearValidityImage = uiimage(app.GearPanel);
            app.GearValidityImage.ImageSource = 'Red x.jpg';
            app.Panel = uipanel(app.GridLayout);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangingFcn = createCallbackFcn(app, @NameChanging, true);
            app.StopTimeEditField_2Label = uilabel(app.Panel);
            app.StopTimeEditField_2Label.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.Panel, 'numeric');
            app.StopTimeEditField.Limits = [0 Inf];
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeValueChangedFcn, true);
            app.StopTimeEditField.Value = 1;
            app.CreateManeuverLabel = uilabel(app.Panel);
            app.CreateManeuverLabel.Text = 'Create Maneuver';
            app.NameValidityLabel = uilabel(app.Panel);
            app.NameValidityLabel.Text = 'Invalid name';
            app.BottomPanel = uipanel(app.GridLayout);
            app.SaveButton = uibutton(app.BottomPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SavedButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.BottomPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.StateLabel = uilabel(app.BottomPanel);
            app.ValidityLabel = uilabel(app.BottomPanel);
            app.ValidityLabel.Text = 'Validity:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = create_maneuver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end